classdef tarea_programada_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SolucionadordefuncionespormtodosnumricosPanel  matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        ParmetrosparaaplicarelmtodoPanel  matlab.ui.container.Panel
        SolucionarButton               matlab.ui.control.Button
        ExportaraExcelButton           matlab.ui.control.Button
        ValorparacriteriodeparoEditFieldLabel  matlab.ui.control.Label
        ValorparacriteriodeparoEditField  matlab.ui.control.NumericEditField
        ValorxlEditFieldLabel          matlab.ui.control.Label
        ValorxlEditField               matlab.ui.control.NumericEditField
        ValorxuEditFieldLabel          matlab.ui.control.Label
        ValorxuEditField               matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        SeleccindelcriteriodeparoDropDownLabel  matlab.ui.control.Label
        SeleccindelcriteriodeparoDropDown  matlab.ui.control.DropDown
        SeleccindelmtodoDropDownLabel  matlab.ui.control.Label
        SeleccindelmtodoDropDown       matlab.ui.control.DropDown
        FuncinEditFieldLabel           matlab.ui.control.Label
        FuncinEditField                matlab.ui.control.EditField
        LaraizcorrespondeaEditFieldLabel  matlab.ui.control.Label
        LaraizcorrespondeaEditField    matlab.ui.control.NumericEditField
        ParmetrosparagraficarlafuncinPanel  matlab.ui.container.Panel
        LmiteinferiorejeXEditFieldLabel  matlab.ui.control.Label
        LmiteinferiorejeXEditField     matlab.ui.control.NumericEditField
        LmitesuperiorejeXEditFieldLabel  matlab.ui.control.Label
        LmitesuperiorejeXEditField     matlab.ui.control.NumericEditField
        PasoejeXEditFieldLabel         matlab.ui.control.Label
        PasoejeXEditField              matlab.ui.control.NumericEditField
        GraficarButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
%****************************************************
%*             AUTOR: CARLOS CORONADO ZÚÑIGA        *
%****************************************************

          %Definición de la función para graficar
           %  con callbak al presionar el boton

            
            %Se declaran variables para almacenar
             %  los valores límites de la funcion
            LimIn = app.LmiteinferiorejeXEditField.Value;
            LimSu = app.LmitesuperiorejeXEditField.Value;
            Paso = app.PasoejeXEditField.Value;
            
            
            %Se guarda en uns variable el string 
            % de la función ingresada por el usuario
            Funcion = app.FuncinEditField.Value;
             %Se corta la parte del string que hacer 
             % referencia a cual es la variable
            Funcion = strrep(Funcion,'@(x)','');
            
            
            %Se declara una variable con rango del eje
            %  x para poder graficar
            x = LimIn: Paso: LimSu;
            
            %Se utiliza la funcion eval, para evaluar 
            % el string en todos los valores x
            y= eval(Funcion);
           
           
            %Se imprimen los resultados con los argumentos del eje x & y
            plot(app.UIAxes,x,y);
        end

        % Button pushed function: SolucionarButton
        function SolucionarButtonPushed(app, event)
            
            %Se declara la función para emplear el método
            %  númerico seleccionado

            format short % presición de cifras despues de la coma 
 
%Se preparan una serie de funciones para verificar los resultados
            
%@(x)-12-21.*x+18.*x.^2-2.75.*x.^3
%@(x)-1+5.5.*x-4.*x.^2+0.5.*x.^3
%@(x)-30+(pi.*3.*3./3).*x.^2-(pi./3).*x.^3
%@(x)-30+(pi.*x.^2).*((9-x)./3)
%@(x)-pi+pi.*x.^2-(pi./3).*x.^3
            
            %Se guarda en variable el valor de criterio de paro
            criterio_paro = app.ValorparacriteriodeparoEditField.Value;
            
            %Se almacena el string de función y se corta el
            %  indicador de variable
            Funcion = app.FuncinEditField.Value;
            Funcion = strrep(Funcion,'@(x)','');
            
            %Se genera una matriz vacía para almacenar los 
            % resultados de la iteracion
            iteraciones_matriz = [];


%-------------------------------------------------------------------------
%Inicio de programa, se evalua el método seleccionado para la solucion
%Se escoge un switch de opciones para la posible integración de más de 2 
% métodos, y se evalua el DropDown


switch app.SeleccindelmtodoDropDown.Value
          
    
%------------------------------------------------------------------------
%------------------------------------------------------------------------
% METODO BISECCION
    case 1     
        
            
            %Selección del criterio de paro por error o por cantidad de
            %operaciones con el dropdown
            
            %Si se escoge criterio de error aprox.
            if app.SeleccindelcriteriodeparoDropDown.Value == 1        
                
                %ERROR POR CRITERIO DE ERROR ABSOLUTO---------------------
                i=1; %contador de iteración set en 1
                ea=100; %error aprox inicial no aplica, valor por defecto
                
                
                %ciclo para iterar hasta condicion de paro con el error abs
                while ea >criterio_paro
                    
                    %se guarda el str funcion semplazandolo por la variable
                    % a evaluar
                    f_xl = strrep(Funcion,'x','xl');
                    f_xu = strrep(Funcion,'x','xu');
                    f_xr = strrep(Funcion,'x','xr');
                    
                    %variable para imprimir la iteración en tabla
                    iter = i;    
                  
                    %si se está en la primera iteración
                    if i == 1
                        
                        %se guardan los valores iniciales introducidos
                        xu= app.ValorxuEditField.Value;
                        xl= app.ValorxlEditField.Value;
                        
                        %se calcula el nuevo valor de la raiz segun el
                        %metodo
                        xr= (xu+xl)./2;
                        
                        
                        % Se evaluan las funciones almacenadas subtituyendo
                        % los valores de la función simbolica sonvertida
                        % desde el str, y transformandolo en un valor
                        % double para mantener presición
                        
                        f_xl = double(subs(str2sym(f_xl)));
                        f_xr = double(subs(str2sym(f_xr)));
                        f_xu = double(subs(str2sym(f_xu)));
                        
                        %se calcula la cond de oscilacion
                        f_xr_por_f_xl= f_xl.*f_xr;
                      
                        
                    %Resto de iteraciones    
                    else
                        
                        %Se almacena valor anterior para calculo del error
                        xr_anterior = xr;
                        
                        %Condición de oscilación para escogencia de limites
                        if f_xr_por_f_xl >= 0
                            xl= xr;
                        else
                            xu = xr;
                        end
                        
                        %Se calcula el nuevo limite (raiz)
                        xr= (xu+xl)./2;
                        
                        %Se avalua la nueva raiz sustituyendo en el str
                        f_xl = double(subs(str2sym(f_xl)));
                        f_xu = double(subs(str2sym(f_xu)));
                        f_xr = double(subs(str2sym(f_xr)));

                        %Se re calcula condicion de oscilacion
                        f_xr_por_f_xl= f_xl.*f_xr;
                        
                        %se determina el error absoluto
                        ea = abs( (xr-xr_anterior)./ xr).*100;
                        
                    end
                    i=i+1; %Se aumenta en 1 el contador de iteraciones
                    
                    
                    %Matriz fila con los resultados de la iteracion
                    iteracion_fila = [iter xl xu xr f_xl f_xu f_xr f_xr_por_f_xl ea];
                    
                    %Matriz tabla se le concatena la nueva fila/itecion
                    iteraciones_matriz = [iteraciones_matriz;iteracion_fila];
                    
                    %se genera una tabla y se guarda en variable
                    Resultados = array2table(iteraciones_matriz,'VariableNames',{'iter' 'xu' 'xl' 'xr' 'f(xl)' 'f(xu)' 'f(xr)' 'f(xl)*f(xr)' 'ea'});
                    
                    %Se definen nombres de etiquetas de tabla segun metodo
                    app.UITable.ColumnName = {'iter' 'xu' 'xl' 'xr' 'f(xl)' 'f(xu)' 'f(xr)' 'f(xl)*f(xr)' 'ea'};
                    
                    %Se envian los resultados a la tabla en la IDE
                    app.UITable.Data = Resultados;
                end

                %ERROR POR CANTIDAD DE ITERACIONES------------------------
            else
                
                %ciclo con criterio de paro indicado
                for i=1:criterio_paro
                    
                    %se guarda el str funcion semplazandolo por la variable
                    % a evaluar
                    f_xl = strrep(Funcion,'x','xl');
                    f_xu = strrep(Funcion,'x','xu');
                    f_xr = strrep(Funcion,'x','xr');
                    
                    %variable para imprimir la iteración en tabla
                    iter = i;
                    
                    %Primera iteracion
                    if i == 1
                        
                        %se guardan los valores iniciales introducidos
                        xu= app.ValorxuEditField.Value;
                        xl= app.ValorxlEditField.Value;
                        
                        %Se calcula nuevo valor limite segun el metodo
                        xr= (xu+xl)./2;
                        
                        %Se avalua la nueva raiz sustituyendo en el str
                        f_xl = double(subs(str2sym(f_xl)));
                        f_xu = double(subs(str2sym(f_xu)));
                        f_xr = double(subs(str2sym(f_xr)));
                        
                        %Se re calcula condicion de oscilacion
                        f_xr_por_f_xl= f_xl.*f_xr;
                        
                        %error aprox inicial no aplica, valor por defecto
                        ea = 100;      
                        
                    else
                        %se almacena al valor para el calculo del error
                        xr_anterior = xr; 
                        
                        %Condición de oscilación inicial para selesccion
                        %de limite izquierdo o derecho
                        if f_xr_por_f_xl >= 0
                            xl= xr;
                        else
                            xu = xr;
                        end
                        
                        
                        %Se calcula nuevo valor limite segun el metodo
                        xr= (xu+xl)./2;
                        
                        %Se avalua la nueva raiz sustituyendo en el str
                        f_xl = double(subs(str2sym(f_xl)));
                        f_xu = double(subs(str2sym(f_xu)));
                        f_xr = double(subs(str2sym(f_xr)));
                        
                        f_xr_por_f_xl= f_xl.*f_xr;
                        
                        %Se calcula el error aprox
                        ea = abs( (xr-xr_anterior)./ xr).*100;
                    end
                    
                    %Matriz fila con los resultados de la iteracion
                    iteracion_fila = [iter xl xu xr f_xl f_xu f_xr f_xr_por_f_xl ea];
                    
                    %Matriz tabla se le concatena la nueva fila/itecion
                    iteraciones_matriz = [iteraciones_matriz;iteracion_fila];
                    
                    %Se definen nombres de etiquetas de tabla segun metodo
                    app.UITable.ColumnName = {'iter' 'xu' 'xl' 'xr' 'f(xl)' 'f(xu)' 'f(xr)' 'f(xl)*f(xr)' 'ea'};
                    
                    %se genera una tabla y se guarda en variable
                    Resultados = array2table(iteraciones_matriz,'VariableNames',{'iter' 'xu' 'xl' 'xr' 'f(xl)' 'f(xr)' 'f(xu)' 'f(xl)*f(xr)' 'ea'});
                    
                    %Se envian los resultados a la tabla en la IDE
                    app.UITable.Data = Resultados;
                    
                end 
                
            end
            
            %Al finalizar la ejecuacion de programa se muestra el resultado
            %final con 4 cifras significativas
            app.LaraizcorrespondeaEditField.Value = xr;
            
%-------------------------------------------------------------------------
% Newton Raphson Modificado
    otherwise  %caso por defecto del switch


    %Selección del criterio de paro por eror o por cantidad de
            %operaciones
            
            if app.SeleccindelcriteriodeparoDropDown.Value == 1        
                
                %ERROR POR CRITERIO DE ERROR ABSOLUTO----------------------
                i=1; %contador iteraciones
                ea=100; %definicion del error por defecto 1era iteracion
                
                
                %comparación con criterio de paro error aprox
                while ea >criterio_paro


                    iter = i; %Cont para imprimir la iteración en tabla  
                    
                    if i == 1 %primera iteracion
                        
                        %Se llama la funcion tipo str y se cambia por la
                        %variable de condicion a evaluar
                        f_xl = strrep(Funcion,'x','xl');
                        
                        %Se convierte str a simbolico para sustituir
                        %calculo de las derivadas segun el metodo con la
                        %funcion diff
                        f_xl = str2sym(f_xl);
                        d1_f_xl = diff((f_xl));
                        d2_f_xl = diff(d1_f_xl);
                        
                        %Se guarda el valor de inicio del usuario
                        xl= app.ValorxlEditField.Value;     
                        
                        %se subtituye en la funcion y sus derivadas el
                        %valor de inicio
                        f_xl = double(subs(f_xl));
                        
                        d1_f_xl = double(subs(d1_f_xl));
                        d2_f_xl = double(subs(d2_f_xl));
                        
                    
                    %Demas iteraciones
                    else 
                        xl_anterior = xl;%se almacena el anterior para 
                        % calculo de error
                        
                        %Se determina la nueva raiz segun el metodo
     xl= xl - ( (f_xl .* d1_f_xl) ./ (((d1_f_xl).^2 )-(f_xl .* d2_f_xl)) );
                        
                        
                        %Dado que las funciones ya fueron remplazadas
                        %es necesario recargar el str para derivar y evaluar
                        %con el nuevo valor
                        f_xl = strrep(Funcion,'x','xl');
                        f_xl = str2sym(f_xl);
                        d1_f_xl = diff((f_xl));
                        d2_f_xl = diff(d1_f_xl);
                        
                        %Se subtituyen los nuevos valores de la raiz
                        f_xl = double((subs(f_xl)));
                        d1_f_xl = double(subs(d1_f_xl));
                        d2_f_xl = double(subs(d2_f_xl));
                        
                        %Se calcula error aprox segun método
                        ea = abs( (xl-xl_anterior)./ xl).*100;
                        
                    end
                    i=i+1; %Se aumenta contador/indica nueva iteracion
                    
                    %se genera matriz con los valores de la iteracion
                    iteracion_fila = [iter xl f_xl d1_f_xl d2_f_xl ea];
                    
                    %se concatenan la fila para la matriz para tabla
                    iteraciones_matriz = [iteraciones_matriz;iteracion_fila];
                    
                    %se generar una tabla con las variables encabezados
Resultados = array2table(iteraciones_matriz,'VariableNames',{'iter' 'xl' 'f_xl' 'd1_f_xl' 'd2_f_xl' 'ea'});
                    
                    %Se cambia el nombre de las columnas segun el metodo
    app.UITable.ColumnName = {'iter' 'xl' 'f_xl' 'd1_f_xl' 'd2_f_xl' 'ea'};          
                    
                    %Se almacena la matriz en la tabla mostrando los
                    %resultados
                    app.UITable.Data = Resultados;
                end

                %ERROR POR CANTIDAD DE ITERACIONES-------------------------
            else
                
                for i=1:criterio_paro %ciclo para cantidad de iteraciones

                    iter = i; %contador de iteraciones
                    
                    %Primera iteracion
                    if i == 1 
                        
                        %se guarda el str de la funcion y se sustituye por
                        %la variable a remplazar
                        f_xl = strrep(Funcion,'x','xl');
                        
                        %Se convierte el str en simbolic y se deriva segun
                        %el método
                        f_xl = str2sym(f_xl);
                        d1_f_xl = diff((f_xl));
                        d2_f_xl = diff(d1_f_xl);
                        
                        %se almacena el valor de inico introducido
                        xl= app.ValorxlEditField.Value;     
                        
                        %Se evalua en la funcion y las derivadas el valor
                        %inicial
                        f_xl = double(subs(f_xl));
                        d1_f_xl = double(subs(d1_f_xl));
                        d2_f_xl = double(subs(d2_f_xl));
                        
                        ea = 100; %error por defecto para la 1era iteracion  
                        
                        
                        %Resto de iteraciones
                    else 
                        xl_anterior = xl;%se almacena para calculo del 
                        % error aprox
                        
     % se define nuevo valor de la raiz segun metodo                  
     xl= xl - ( (f_xl .* d1_f_xl) ./ (((d1_f_xl).^2 )-(f_xl .* d2_f_xl)) );
                        
     %Dado que las funciones ya fueron evaluadas, se recargan el str y se
     %vuelve a derivar para poder evaluar en el nuevo valor xl
                        f_xl = strrep(Funcion,'x','xl');
                        f_xl = str2sym(f_xl);
                        d1_f_xl = diff((f_xl));
                        d2_f_xl = diff(d1_f_xl);
                        
      %Se evaluan en el nuevo valor calculado
                        f_xl = double((subs(f_xl)));
                        d1_f_xl = double(subs(d1_f_xl));
                        d2_f_xl = double(subs(d2_f_xl));
                       
                        %Se determina el error aproximado
                        ea = abs( (xl-xl_anterior)./ xl).*100;
                    end
                    
                    %Matriz fila con los resultados de la iteracion
                    iteracion_fila = [iter xl f_xl d1_f_xl d2_f_xl ea];
                    
                    %Matriz tabla se le concatena la nueva fila/itecion
                    iteraciones_matriz = [iteraciones_matriz;iteracion_fila];
                    
                    %se genera una tabla y se guarda en variable
                    Resultados = array2table(iteraciones_matriz,'VariableNames',{'iter' 'xl' 'f_xl' 'd1_f_xl' 'd2_f_xl' 'ea'});
                   
                    %Se definen nombres de etiquetas de tabla segun metodo
                    app.UITable.ColumnName = {'iter' 'xl' 'f_xl' 'd1_f_xl' 'd2_f_xl' 'ea'};
                     
                    %Se envian los resultados a la tabla en la IDE
                    app.UITable.Data = Resultados;
                      
                    
                end 
                
            end
            app.LaraizcorrespondeaEditField.Value = xl; %Se muestra el
           %resultado final de la raiz en un cuadro con 4 cifras despues de
           %la coma


end
        end

        % Value changed function: SeleccindelmtodoDropDown
        function SeleccindelmtodoDropDownValueChanged(app, event)
         
        end

        % Button pushed function: ExportaraExcelButton
        function ExportaraExcelButtonPushed(app, event)
%Funcion para exportar los valores en la tabla a Excel
            
            %se guardan los datos de la tabla
            data = get(app.UITable, 'data');
            %se declara el tipo de extension de archivo de excel
            [file, path] = uiputfile('*.xlsx');
            %Se almacena el nombre para el archivo ingresado
            filename= fullfile(path,file);
            %Se guarda la tabla
            writetable(data, filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SolucionadordefuncionespormtodosnumricosPanel = uipanel(app.UIFigure);
            app.SolucionadordefuncionespormtodosnumricosPanel.Title = 'Solucionador de funciones por métodos numéricos';
            app.GridLayout = uigridlayout(app.SolucionadordefuncionespormtodosnumricosPanel);
            app.ParmetrosparaaplicarelmtodoPanel = uipanel(app.GridLayout);
            app.ParmetrosparaaplicarelmtodoPanel.Title = 'Parámetros para aplicar el método';
            app.SolucionarButton = uibutton(app.ParmetrosparaaplicarelmtodoPanel, 'push');
            app.SolucionarButton.ButtonPushedFcn = createCallbackFcn(app, @SolucionarButtonPushed, true);
            app.SolucionarButton.Text = 'Solucionar';
            app.ExportaraExcelButton = uibutton(app.ParmetrosparaaplicarelmtodoPanel, 'push');
            app.ExportaraExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportaraExcelButtonPushed, true);
            app.ExportaraExcelButton.Text = 'Exportar a Excel';
            app.ValorparacriteriodeparoEditFieldLabel = uilabel(app.ParmetrosparaaplicarelmtodoPanel);
            app.ValorparacriteriodeparoEditFieldLabel.Text = 'Valor para criterio de paro';
            app.ValorparacriteriodeparoEditField = uieditfield(app.ParmetrosparaaplicarelmtodoPanel, 'numeric');
            app.ValorparacriteriodeparoEditField.Value = 1;
            app.ValorxlEditFieldLabel = uilabel(app.ParmetrosparaaplicarelmtodoPanel);
            app.ValorxlEditFieldLabel.Text = 'Valor xl';
            app.ValorxlEditField = uieditfield(app.ParmetrosparaaplicarelmtodoPanel, 'numeric');
            app.ValorxuEditFieldLabel = uilabel(app.ParmetrosparaaplicarelmtodoPanel);
            app.ValorxuEditFieldLabel.Text = 'Valor xu';
            app.ValorxuEditField = uieditfield(app.ParmetrosparaaplicarelmtodoPanel, 'numeric');
            app.UITable = uitable(app.ParmetrosparaaplicarelmtodoPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.SeleccindelcriteriodeparoDropDownLabel = uilabel(app.ParmetrosparaaplicarelmtodoPanel);
            app.SeleccindelcriteriodeparoDropDownLabel.Text = 'Selección del criterio de paro';
            app.SeleccindelcriteriodeparoDropDown = uidropdown(app.ParmetrosparaaplicarelmtodoPanel);
            app.SeleccindelcriteriodeparoDropDown.Items = {'Error relativo aproximado %', 'Cantidad de iteraciones'};
            app.SeleccindelcriteriodeparoDropDown.ItemsData = [1 2];
            app.SeleccindelcriteriodeparoDropDown.Value = 1;
            app.SeleccindelmtodoDropDownLabel = uilabel(app.ParmetrosparaaplicarelmtodoPanel);
            app.SeleccindelmtodoDropDownLabel.Text = 'Selección del método';
            app.SeleccindelmtodoDropDown = uidropdown(app.ParmetrosparaaplicarelmtodoPanel);
            app.SeleccindelmtodoDropDown.Items = {'Bisección', 'Newton-Raphson modificado'};
            app.SeleccindelmtodoDropDown.ItemsData = [1 2 3 4];
            app.SeleccindelmtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccindelmtodoDropDownValueChanged, true);
            app.SeleccindelmtodoDropDown.Value = 1;
            app.FuncinEditFieldLabel = uilabel(app.ParmetrosparaaplicarelmtodoPanel);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.ParmetrosparaaplicarelmtodoPanel, 'text');
            app.LaraizcorrespondeaEditFieldLabel = uilabel(app.ParmetrosparaaplicarelmtodoPanel);
            app.LaraizcorrespondeaEditFieldLabel.Text = 'La raiz corresponde a';
            app.LaraizcorrespondeaEditField = uieditfield(app.ParmetrosparaaplicarelmtodoPanel, 'numeric');
            app.LaraizcorrespondeaEditField.ValueDisplayFormat = '%.4f';
            app.LaraizcorrespondeaEditField.Editable = 'off';
            app.ParmetrosparagraficarlafuncinPanel = uipanel(app.GridLayout);
            app.ParmetrosparagraficarlafuncinPanel.Title = 'Parámetros para graficar la función';
            app.LmiteinferiorejeXEditFieldLabel = uilabel(app.ParmetrosparagraficarlafuncinPanel);
            app.LmiteinferiorejeXEditFieldLabel.Text = 'Límite inferior eje X';
            app.LmiteinferiorejeXEditField = uieditfield(app.ParmetrosparagraficarlafuncinPanel, 'numeric');
            app.LmiteinferiorejeXEditField.Value = -10;
            app.LmitesuperiorejeXEditFieldLabel = uilabel(app.ParmetrosparagraficarlafuncinPanel);
            app.LmitesuperiorejeXEditFieldLabel.Text = 'Límite superior eje X';
            app.LmitesuperiorejeXEditField = uieditfield(app.ParmetrosparagraficarlafuncinPanel, 'numeric');
            app.LmitesuperiorejeXEditField.Value = 10;
            app.PasoejeXEditFieldLabel = uilabel(app.ParmetrosparagraficarlafuncinPanel);
            app.PasoejeXEditFieldLabel.Text = 'Paso eje X';
            app.PasoejeXEditField = uieditfield(app.ParmetrosparagraficarlafuncinPanel, 'numeric');
            app.PasoejeXEditField.Value = 0.1;
            app.GraficarButton = uibutton(app.ParmetrosparagraficarlafuncinPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.UIAxes = uiaxes(app.ParmetrosparagraficarlafuncinPanel);
            title(app.UIAxes, 'Gráfica de la función')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tarea_programada_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end